classdef Interface_Graphique < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LabelRappel_2        matlab.ui.control.Label
        LabelPrecision_2     matlab.ui.control.Label
        RappelLabel_2        matlab.ui.control.Label
        PrcisionLabel_2      matlab.ui.control.Label
        YCbCrButton          matlab.ui.control.Button
        TraitementLabel_2    matlab.ui.control.Label
        TraitementLabel      matlab.ui.control.Label
        LabelRappel          matlab.ui.control.Label
        LabelPrecision       matlab.ui.control.Label
        RappelLabel          matlab.ui.control.Label
        PrcisionLabel        matlab.ui.control.Label
        RGBButton            matlab.ui.control.Button
        FiltreDropDown       matlab.ui.control.DropDown
        FiltreDropDownLabel  matlab.ui.control.Label
        RECHERCHECBIRLabel   matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        img;
        pathFolder;
        features_list;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ChargerImageButtonPushed(app, event)
            % Ouvrir une boîte de dialogue pour sélectionner un fichier image
            [file, app.pathFolder] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp;*.tif)'});
        end

        % Button pushed function: RGBButton
        function RGBButtonPushed(app, event)

            dirname = "../VisTexColor40_2024/"; % Répertoire contenant les images
            nlevels = 3;                 % Nombre de niveaux de décomposition des ondelettes
            nsubs = 16;                  % Nombre d'images par classe (VisTex par défaut)
            TopN = 16;                   % Nombre d'images les plus proches à considérer
            wavelet = app.FiltreDropDown.Value;
            
            % Appel de la fonction principale CBIR_Simple
            [Precision, Recall, pc, rc] = CBIR_Simple(dirname, nlevels, nsubs, TopN,wavelet);
            disp(['precision = ', num2str(Precision)]);  % Affiche la précision
            disp(['rappel = ', num2str(Recall)]);  % Affiche le rappel 
            plot_bar(pc) %diagramme à Barre pour pc
            app.LabelPrecision.Text = num2str(Precision);
            app.LabelRappel.Text = num2str(Recall);
            
            function plot_bar(pc)
                % Création d'une figure 
                figure;
                
                % Diagramme à barres pour la précision (pc)
                bar(pc);
                xlabel('Classes');
                ylabel('Précision');
                title('Diagramme à Barres pour la Précision');
                % Modifier l'échelle de l'axe des x pour afficher les valeurs sous chaque barre
                xticks(1:length(pc)); % Définir les positions des ticks
                xticklabels(1:length(pc)); % Définir les labels des ticks (1, 2, 3, ..., n)
                
            end
            app.TraitementLabel.Text = 'Resultat trouvé';  
        end

        % Button pushed function: YCbCrButton
        function YCbCrButtonPushed(app, event)
            dirname = "../VisTexColor40_2024/"; % Répertoire contenant les images
            nlevels = 3;                 % Nombre de niveaux de décomposition des ondelettes
            nsubs = 16;                  % Nombre d'images par classe (VisTex par défaut)
            TopN = 16;                   % Nombre d'images les plus proches à considérer
            wavelet = app.FiltreDropDown.Value;

            % Appel de la fonction principale CBIR_Simple
            %true ici spécifie qu'il faut convertir les images au format ycbcr
            [Precision, Recall, pc, rc] = CBIR_Simple(dirname, nlevels, nsubs, TopN,wavelet, true);
            disp(['precision = ', num2str(Precision)]);  % Affiche la précision
            disp(['rappel = ', num2str(Recall)]);  % Affiche le rappel 
            plot_bar(pc) %diagramme à Barre pour pc
            app.LabelPrecision_2.Text = num2str(Precision);
            app.LabelRappel_2.Text = num2str(Recall);
            
            function plot_bar(pc)
                % Création d'une figure 
                figure;
                
                % Diagramme à barres pour la précision (pc)
                bar(pc);
                xlabel('Classes');
                ylabel('Précision');
                title('Diagramme à Barres pour la Précision en utilisant le format ycbcr');
                % Modifier l'échelle de l'axe des x pour afficher les valeurs sous chaque barre
                xticks(1:length(pc)); % Définir les positions des ticks
                xticklabels(1:length(pc)); % Définir les labels des ticks (1, 2, 3, ..., n)
    
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RECHERCHECBIRLabel = uilabel(app.UIFigure);
            app.RECHERCHECBIRLabel.Interpreter = 'tex';
            app.RECHERCHECBIRLabel.Text = 'RECHERCHE CBIR';
            app.FiltreDropDownLabel = uilabel(app.UIFigure);
            app.FiltreDropDownLabel.Text = 'Filtre';
            app.FiltreDropDown = uidropdown(app.UIFigure);
            app.FiltreDropDown.Items = {'bior2.2', 'bior2.4'};
            app.FiltreDropDown.Value = 'bior2.2';
            app.RGBButton = uibutton(app.UIFigure, 'push');
            app.RGBButton.ButtonPushedFcn = createCallbackFcn(app, @RGBButtonPushed, true);
            app.RGBButton.Text = 'RGB';
            app.PrcisionLabel = uilabel(app.UIFigure);
            app.PrcisionLabel.Text = 'Précision:';
            app.RappelLabel = uilabel(app.UIFigure);
            app.RappelLabel.Text = 'Rappel:';
            app.LabelPrecision = uilabel(app.UIFigure);
            app.LabelPrecision.Text = '';
            app.LabelRappel = uilabel(app.UIFigure);
            app.LabelRappel.Text = '';
            app.TraitementLabel = uilabel(app.UIFigure);
            app.TraitementLabel.Interpreter = 'html';
            app.TraitementLabel.Text = '';
            app.TraitementLabel_2 = uilabel(app.UIFigure);
            app.TraitementLabel_2.Interpreter = 'html';
            app.TraitementLabel_2.Text = '';
            app.YCbCrButton = uibutton(app.UIFigure, 'push');
            app.YCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @YCbCrButtonPushed, true);
            app.YCbCrButton.Text = 'YCbCr';
            app.PrcisionLabel_2 = uilabel(app.UIFigure);
            app.PrcisionLabel_2.Text = 'Précision:';
            app.RappelLabel_2 = uilabel(app.UIFigure);
            app.RappelLabel_2.Text = 'Rappel:';
            app.LabelPrecision_2 = uilabel(app.UIFigure);
            app.LabelPrecision_2.Text = '';
            app.LabelRappel_2 = uilabel(app.UIFigure);
            app.LabelRappel_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_Graphique

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end